classdef About < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Panel                         matlab.ui.container.Panel
        AboutUsLabel                  matlab.ui.control.Label
        UIAxesC                       matlab.ui.control.UIAxes
        AbdelmounaimAmmariButton      matlab.ui.control.Button
        UIAxesD                       matlab.ui.control.UIAxes
        BilalElbadaouyButton          matlab.ui.control.Button
        UIAxesA                       matlab.ui.control.UIAxes
        IsmailBenAllaButton           matlab.ui.control.Button
        UIAxesB                       matlab.ui.control.UIAxes
        AhmedLaghrissiButton          matlab.ui.control.Button
        SupervisorPrImadAzzaziButton  matlab.ui.control.Button
        ENSAFesLabel                  matlab.ui.control.Label
        HomeButton                    matlab.ui.control.Button
    end


    properties (Access = private)
        callingapp; % Description
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Home)
            app.callingapp = Home;
            
            NameA=strcat('','PictureA.jpg');
            imgA=imread(NameA);
            NameB=strcat('','PictureB.jpg');
            imgB=imread(NameB);
            NameC=strcat('','PictureC.jpg');
            imgC=imread(NameC);
            NameD=strcat('','PictureD.jpg');
            imgD=imread(NameD);
            
            imshow(imgA,'Parent',app.UIAxesA);
            imshow(imgB,'Parent',app.UIAxesB);
            imshow(imgC,'Parent',app.UIAxesC);
            imshow(imgD,'Parent',app.UIAxesD);
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
 
             delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Panel = uipanel(app.UIFigure);
            app.AboutUsLabel = uilabel(app.Panel);
            app.AboutUsLabel.Text = 'About Us';
            app.UIAxesC = uiaxes(app.Panel);
            app.AbdelmounaimAmmariButton = uibutton(app.Panel, 'push');
            app.AbdelmounaimAmmariButton.Text = 'Abdelmounaim Ammari';
            app.UIAxesD = uiaxes(app.Panel);
            app.BilalElbadaouyButton = uibutton(app.Panel, 'push');
            app.BilalElbadaouyButton.Text = 'Bilal Elbadaouy';
            app.UIAxesA = uiaxes(app.Panel);
            app.IsmailBenAllaButton = uibutton(app.Panel, 'push');
            app.IsmailBenAllaButton.Text = 'Ismail Ben Alla';
            app.UIAxesB = uiaxes(app.Panel);
            app.AhmedLaghrissiButton = uibutton(app.Panel, 'push');
            app.AhmedLaghrissiButton.Text = 'Ahmed Laghrissi';
            app.SupervisorPrImadAzzaziButton = uibutton(app.Panel, 'push');
            app.SupervisorPrImadAzzaziButton.Text = 'Supervisor : Pr. Imad Azzazi';
            app.ENSAFesLabel = uilabel(app.Panel);
            app.ENSAFesLabel.Text = 'ENSA Fes';
            app.HomeButton = uibutton(app.Panel, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
        end
    end

    methods (Access = public)

        % Construct app
        function app = About(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end