classdef SecureApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VoiceRecognition               matlab.ui.Figure
        RECORDButton                   matlab.ui.control.Button
        Apply                          matlab.ui.control.Button
        ENSAFStudentsLabel             matlab.ui.control.Label
        VoiceRecognitionApplicationLabel  matlab.ui.control.Label
        MadeByLabel                    matlab.ui.control.Label
        BenAllaIsmailLabel             matlab.ui.control.Label
        LaghrissiAhmedLabel            matlab.ui.control.Label
        BilalelbadaouyLabel            matlab.ui.control.Label
        AmmariabdelmounaimLabel        matlab.ui.control.Label
        SupervisedbyPrImadAzzaziLabel  matlab.ui.control.Label
        SpeakerName                    matlab.ui.control.EditField
        SAVEButton                     matlab.ui.control.Button
        UserNameLabel                  matlab.ui.control.Label
        AddUserButton                  matlab.ui.control.Button
        nbrofSamplesfortrainingLabel   matlab.ui.control.Label
        samples                        matlab.ui.control.NumericEditField
        nbrOfDataBase                  matlab.ui.control.Label
        databasesizeLabel              matlab.ui.control.Label
        Users                          matlab.ui.control.Label
        nbrofusersLabel                matlab.ui.control.Label
        alert                          matlab.ui.control.Label
        isRecording                    matlab.ui.control.Label
        Mess                           matlab.ui.control.Label
        aboutButton                    matlab.ui.control.Button
    end

    properties (Access = private)
        nbrOfSamples = 1;
        nbrOfUsers = 0;
        nbrOfData = 1;
        test = 0;
        aboutapp;
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SAVEButton.Enable = 'off';
            app.RECORDButton.Enable = 'off';
            app.samples.Editable = 'off';
            app.SpeakerName.Editable = 'off';
            app.Users.Text = num2str(app.nbrOfUsers);
        end

        % Button pushed function: RECORDButton
        function RECORDButtonPushed(app, event)
            if app.test == 0
                Name = app.SpeakerName.Value;
                app.isRecording.Text = "Recording ...";
                TrainOurModul(Name,app.nbrOfData);
                app.isRecording.Text = " ";
                Text = "Audio Number "+app.nbrOfSamples;
                Text = cellstr(Text);
                app.alert.Text = Text{1};
                if app.nbrOfSamples == app.samples.Value
                    app.AddUserButton.Enable = 'on';
                    app.RECORDButton.Enable = 'off';
                    app.alert.Text = "";
                    app.isRecording.Text = "";
                    app.nbrOfSamples = 1; 
                end
                app.nbrOfData = app.nbrOfData + 1;
                app.nbrOfDataBase.Text = num2str(app.nbrOfData);
                app.nbrOfSamples = app.nbrOfSamples + 1;
            else
                file = fopen('Users.txt');
                read = textscan(file, '%s');
                fclose(file);
                app.nbrOfUsers = length(string(read{1}));
                file = fopen('list_train.txt');
                read = textscan(file, '%s');
                fclose(file);
                app.nbrOfData = length(string(read{1}))/2;
                
                app.nbrOfDataBase.Text = num2str(app.nbrOfData);
                app.Users.Text = num2str(app.nbrOfUsers);
                
                app.isRecording.Text = "Recording ...";
                message = TestOurModul(app.nbrOfData/app.nbrOfUsers,app.nbrOfData);
                app.isRecording.Text = "";
                app.Mess.Text = message;
            end
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            app.SAVEButton.Enable = 'off';
            N = app.samples.Value;
%             app.nbrOfDataBase.Text = num2str(N);
            name = app.SpeakerName.Value;
            WriteInfile(name,0,1);  % save name in User name
            app.RECORDButton.Enable = 'on';
            app.samples.Editable = 'off';
            app.SpeakerName.Editable = 'off';
        end

        % Button pushed function: AddUserButton
        function AddUserButtonPushed(app, event)
            app.SAVEButton.Enable = 'on';
            app.SpeakerName.Editable = 'on';
            if app.nbrOfData == 0
                app.samples.Editable = 'on';
            end
            app.AddUserButton.Enable = 'off';
            app.nbrOfUsers = app.nbrOfUsers + 1;
            app.Users.Text = num2str(app.nbrOfUsers);
        end

        % Callback function
        function TurnOnValueChanged(app, event)

        end

        % Button pushed function: Apply
        function ApplyButtonPushed(app, event)
           
            app.AddUserButton.Enable = 'off';
            app.SAVEButton.Enable = 'off';
            app.RECORDButton.Enable = 'on';
            app.test = 1;
        end

        % Button pushed function: aboutButton
        function aboutButtonPushed(app, event)
            app.aboutapp = About(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VoiceRecognition = uifigure;
            app.VoiceRecognition.Name = 'UI Figure';
            app.RECORDButton = uibutton(app.VoiceRecognition, 'push');
            app.RECORDButton.ButtonPushedFcn = createCallbackFcn(app, @RECORDButtonPushed, true);
            app.RECORDButton.Text = 'RECORD';
            app.Apply = uibutton(app.VoiceRecognition, 'push');
            app.Apply.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.Apply.Text = 'Test Modul';
            app.ENSAFStudentsLabel = uilabel(app.VoiceRecognition);
            app.ENSAFStudentsLabel.Text = 'ENSAF Students    ';
            app.VoiceRecognitionApplicationLabel = uilabel(app.VoiceRecognition);
            app.VoiceRecognitionApplicationLabel.Text = 'Voice Recognition Application';
            app.MadeByLabel = uilabel(app.VoiceRecognition);
            app.MadeByLabel.Text = 'Made By :';
            app.BenAllaIsmailLabel = uilabel(app.VoiceRecognition);
            app.BenAllaIsmailLabel.Text = 'Ben Alla Ismail';
            app.LaghrissiAhmedLabel = uilabel(app.VoiceRecognition);
            app.LaghrissiAhmedLabel.Text = 'Laghrissi Ahmed';
            app.BilalelbadaouyLabel = uilabel(app.VoiceRecognition);
            app.BilalelbadaouyLabel.Text = 'Bilal elbadaouy';
            app.AmmariabdelmounaimLabel = uilabel(app.VoiceRecognition);
            app.AmmariabdelmounaimLabel.Text = 'Ammari abdelmounaim';
            app.SupervisedbyPrImadAzzaziLabel = uilabel(app.VoiceRecognition);
            app.SupervisedbyPrImadAzzaziLabel.Text = 'Supervised by Pr.Imad Azzazi';
            app.SpeakerName = uieditfield(app.VoiceRecognition, 'text');
            app.SAVEButton = uibutton(app.VoiceRecognition, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Text = 'SAVE';
            app.UserNameLabel = uilabel(app.VoiceRecognition);
            app.UserNameLabel.Text = 'User Name';
            app.AddUserButton = uibutton(app.VoiceRecognition, 'push');
            app.AddUserButton.ButtonPushedFcn = createCallbackFcn(app, @AddUserButtonPushed, true);
            app.AddUserButton.Text = 'Add User';
            app.nbrofSamplesfortrainingLabel = uilabel(app.VoiceRecognition);
            app.nbrofSamplesfortrainingLabel.Text = 'nbr of Samples for training';
            app.samples = uieditfield(app.VoiceRecognition, 'numeric');
            app.nbrOfDataBase = uilabel(app.VoiceRecognition);
            app.nbrOfDataBase.Text = '0';
            app.databasesizeLabel = uilabel(app.VoiceRecognition);
            app.databasesizeLabel.Text = 'database size : ';
            app.Users = uilabel(app.VoiceRecognition);
            app.Users.Text = '0';
            app.nbrofusersLabel = uilabel(app.VoiceRecognition);
            app.nbrofusersLabel.Text = 'nbr of users : ';
            app.alert = uilabel(app.VoiceRecognition);
            app.alert.Text = '';
            app.isRecording = uilabel(app.VoiceRecognition);
            app.isRecording.Text = '';
            app.Mess = uilabel(app.VoiceRecognition);
            app.Mess.Text = '';
            app.aboutButton = uibutton(app.VoiceRecognition, 'push');
            app.aboutButton.ButtonPushedFcn = createCallbackFcn(app, @aboutButtonPushed, true);
            app.aboutButton.Text = 'about';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SecureApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VoiceRecognition)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VoiceRecognition)
        end
    end
end